classdef gui_3D_SMT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ebFRETTransformationButton     matlab.ui.control.Button
        SelectFilesforebFRETPanel      matlab.ui.control.EditField
        SelectFilesforebFRETButton     matlab.ui.control.Button
        Panel                          matlab.ui.container.Panel
        kaEditField                    matlab.ui.control.EditField
        kaEditFieldLabel               matlab.ui.control.Label
        koffEditField                  matlab.ui.control.EditField
        koffEditFieldLabel             matlab.ui.control.Label
        konEditField                   matlab.ui.control.EditField
        konEditFieldLabel              matlab.ui.control.Label
        P22EditField                   matlab.ui.control.EditField
        P22EditFieldLabel              matlab.ui.control.Label
        P21EditField                   matlab.ui.control.EditField
        P21EditFieldLabel              matlab.ui.control.Label
        P12EditField                   matlab.ui.control.EditField
        P12EditFieldLabel              matlab.ui.control.Label
        P11EditField                   matlab.ui.control.EditField
        P11EditFieldLabel              matlab.ui.control.Label
        SaveresultsasButton            matlab.ui.control.Button
        KineticsRateCalculationButton  matlab.ui.control.Button
        bondstateLabel                 matlab.ui.control.Label
        unboundstateLabel              matlab.ui.control.Label
        ebFRETButton                   matlab.ui.control.Button
        VisualizationPanel             matlab.ui.container.Panel
        Lifetimes                      matlab.ui.control.UIAxes
        Fluorescence                   matlab.ui.control.UIAxes
        Intensity                      matlab.ui.control.UIAxes
        OpenPathButton                 matlab.ui.control.Button
        SavePathButton                 matlab.ui.control.Button
        IRFOpenNameButton              matlab.ui.control.Button
        FluorOpenNameButton            matlab.ui.control.Button
        OpenPathEditField              matlab.ui.control.EditField
        SavePathEditField              matlab.ui.control.EditField
        IRFOpenNameEditField           matlab.ui.control.EditField
        FluoroOpenNameEditField        matlab.ui.control.EditField
        IntensityandDurationThresholdPanel  matlab.ui.container.Panel
        MaxDurationPanel               matlab.ui.control.NumericEditField
        MinDurationPanel               matlab.ui.control.NumericEditField
        MaxCountPanel                  matlab.ui.control.NumericEditField
        ExtractTrajectoriesButton      matlab.ui.control.Button
        MaxDurationEditField           matlab.ui.control.Label
        MaxCountEditField              matlab.ui.control.Label
        MinDurationEditField           matlab.ui.control.Label
        TrajectoryInformationPanel     matlab.ui.container.Panel
        LifetimeAnalysisMethodLabel    matlab.ui.control.Label
        DropDown                       matlab.ui.control.DropDown
        LifetimeThresholdPanel         matlab.ui.container.Panel
        SaveNamePanel                  matlab.ui.control.EditField
        MaxLifetimePanel               matlab.ui.control.NumericEditField
        MinLifetimePanel               matlab.ui.control.NumericEditField
        SaveNameEditField              matlab.ui.control.Label
        MinLifetimeEditField           matlab.ui.control.Label
        MaxLifetimeEditField           matlab.ui.control.Label
        LifetimeTracesButton           matlab.ui.control.Button
    end

    
    properties (Access = private)
        Property % Description
        openpath
        savepath
        IRFopenName
        IRFsavename
        FluoropenName
        maxCount
        maxDuration
        minDuration
        minLifetime
        maxLifetime
        saveresultpath
        del
        saveLifetimeName
        ebFRETSaveName
        matFile
    end
    
    methods (Access = private)
       
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: OpenPathButton
        function OpenPathButtonPushed(app, event)
           [path] = uigetdir();
           app.OpenPathEditField.Value = fullfile(path) + "\";
           app.openpath = app.OpenPathEditField.Value;
        end

        % Button pushed function: IRFOpenNameButton
        function IRFOpenNameButtonPushed(app, event)
           [file] = uigetfile(app.openpath);
           app.IRFOpenNameEditField.Value = fullfile(file);
           app.IRFopenName = app.IRFOpenNameEditField.Value;
           
           app.IRFsavename = 'IRF.mat';
           IRFres = 0.128;
           %addpath("Demo_education\")
           IRFcounts = read_phd_func(app.openpath,app.IRFopenName,app.IRFsavename);
        end

        % Button pushed function: FluorOpenNameButton
        function FluorOpenNameButtonPushed(app, event)
           [file] = uigetfile(app.openpath);
           app.FluoroOpenNameEditField.Value = fullfile(file);
           app.FluoropenName = app.FluoroOpenNameEditField.Value;
           
           Fluorsavename = 'Fluor.mat';
           Fluorres = 0.004;
           Fluorcounts = read_phd_func(app.openpath,app.FluoropenName,Fluorsavename);
           app.del = GetDelay_func(app.openpath,Fluorsavename,Fluorres);
        end

        % Button pushed function: SavePathButton
        function SavePathButtonPushed(app, event)
           [path] = uigetdir();
           app.SavePathEditField.Value = fullfile(path) + "\";
           app.savepath = app.SavePathEditField.Value;
           app.saveresultpath = app.savepath;
        end

        % Button pushed function: ExtractTrajectoriesButton
        function ExtractTrajectoriesButtonPushed(app, event)
           files = dir(fullfile(app.openpath, '*.pt3')) ;
            nn = zeros(1, length(files)) ;
            for f = 1:length(files)
                nn(f) = str2double(files(f).name(1:end-4)) ;
            end
            % Extract intensity and lifetime information for all traj
            for a=1:length(nn)
                i = nn(a) ;
                expid_all=i;        % variables for ExtractTrajInfo
                %addpath("Demo_education\")
                % Extract the trajectory information from LabVIEW output and write to the .xls file
                ExtractTrajInfo_func(expid_all,app.maxCount,app.minDuration,app.maxDuration,app.openpath,app.saveresultpath);
                % Generate the histogram and write to the .bin file
                SMT_GenerateHist_func(app.openpath,app.savepath,expid_all,app.saveresultpath);
                % Compensate the delay by shifting and sum them up, write to the
                % fit_128ps_XX.mat
                SMT_Baseline_Lifetime_func(app.savepath,app.openpath,expid_all,app.del,app.saveresultpath);
                % First, prefit the model with the base lifetime, then fit each% lifetime based on the prefitted model
                SMT_ReadTau_Time_func2(app.savepath,app.openpath,app.IRFsavename,expid_all,app.del,app.saveresultpath);
            end
        end

        % Value changed function: MaxCountPanel
        function MaxCountPanelValueChanged(app, event)
            value = app.MaxCountPanel.Value;
            app.maxCount = value;
        end

        % Value changed function: MinDurationPanel
        function MinDurationPanelValueChanged(app, event)
            value = app.MinDurationPanel.Value;
            app.minDuration = value;
        end

        % Value changed function: MaxDurationPanel
        function MaxDurationPanelValueChanged(app, event)
            value = app.MaxDurationPanel.Value;
            app.maxDuration = value;
        end

        % Value changed function: MinLifetimePanel
        function MinLifetimePanelValueChanged(app, event)
            value = app.MinLifetimePanel.Value;
            app.minLifetime = value;
        end

        % Value changed function: MaxLifetimePanel
        function MaxLifetimePanelValueChanged(app, event)
            value = app.MaxLifetimePanel.Value;
            app.maxLifetime = value;
        end

        % Button pushed function: LifetimeTracesButton
        function LifetimeTracesButtonPushed(app, event)
            files = dir(fullfile([app.openpath(1:end-2), '1\'], '*.pt3')) ;
            num = zeros(1, length(files)) ;
            for f = 1:length(files)
                num(f) = str2double(files(f).name(1:end-4)) ;
            end
            preProcess = [3, app.minLifetime, app.maxLifetime] ;  %[time interval; minimum lifetime; maximum lifetime]
            % =========================================================================
            
            tau_track = {} ;
            S_track = {} ;
            elem = [] ;
            % Use for loop to extract all the information I want from several files
            for a = 1:length(num)
                j = num(a) ;
                load([app.savepath, 'tau_all_', num2str(j), '.mat']) ;
                load([app.savepath, 'timestep_', num2str(j), '.mat']) ;
                load([app.savepath, 'S', num2str(j), '_bin.mat']) ;
                temp = xlsread([app.savepath, '_original_', num2str(j), '.xls']) ;
                for i = 1 : length(sticker)
                    if i == 1
                        startindex = 1 ;
                    else
                        startindex = sticker(i-1) + 1 ;
                    end
                    endindex = sticker(i) ;
                    tau_track = [tau_track, tau_all(startindex:endindex)]; 
                    S_track = [S_track, S_all(startindex:endindex)];    
                end
                minus = sum(temp(:,4)<0.015) ;
                elem = [elem, i-minus] ;
            end
            tau_track = tau_track' ;
            S_track = S_track' ;
            % =========================================================================
            
            minilength = preProcess(1) ;    
            num_track = size(tau_track);
            elem_del = [] ;     % Here I want to obtain the trajID that I remove
            for i=1:num_track
               num_obs = length(tau_track{i});
               if num_obs < minilength      % If the tracking duration is less than the threshold
                    tau_track{i} = []; 
                    S_track{i} = [];
                    elem_del = [ elem_del , i ] ;
               end
               if num_obs > 100             % If the tracking duration is longer than the threshold
                    tau_track{i} = [];
                    S_track{i} = [];
                    elem_del = [ elem_del , i ] ;
               end
               if max(tau_track{i}) > preProcess(3) % If the tracking duration is longer than the threshold
                    tau_track{i} = [];
                    S_track{i} = [];
                    elem_del = [ elem_del , i ] ;
               end
               if min(tau_track{i}) < preProcess(2)  % If the tracking duration is shorter than the threshold
                    tau_track{i} = [];
                    S_track{i} = [];
                    elem_del = [ elem_del , i ] ;
               end
            end
            tau_track=tau_track(~cellfun('isempty',tau_track));  
            S_track=S_track(~cellfun('isempty',S_track));  
            % =========================================================================
            
            save([app.savepath, app.saveLifetimeName, '.mat'], 'tau_track')
            % =========================================================================
            
            remain_order_list = [] ; 
            for i = 1:sum(elem)
                if (isempty(find(elem_del==i)))
                    remain_order_list =  [remain_order_list, i];
                end
            end
            remain = cell(length(elem), 1) ;
            for i = 1 : length(remain_order_list)
                if remain_order_list(i) <= elem(1)
                    remain{1} = [ remain{1}, remain_order_list(i)] ;
                end
                for j = 2 : length(elem)
                    if remain_order_list(i) <= sum(elem(1:j)) && remain_order_list(i) > sum(elem(1:j-1))
                        remain{j} = [ remain{j}, remain_order_list(i)-sum(elem(1:j-1))] ;
                    end
                end
            end
            % Save the trajID of the remaining trajectory 
            save([app.savepath, 'rem_', app.saveLifetimeName, '.mat'], 'remain')
            % =================================================================
            
            int2plot = []; tau2plot = []; num_track = size(tau_track);
            timestep = []; b = 1;
            for i = 1:num_track
               int2plot = [int2plot,S_track{i}];    
               tau2plot = [tau2plot,tau_track{i}];    
               num_obs = length(tau_track{i});
               timestep = [timestep, num_obs];
            end
            
            ts2plot = cumsum(timestep);
            subplot(2,1,1)
            hold on
            plot(app.Intensity, 1:length(int2plot), int2plot, 'LineWidth', 1.25, 'Color', 'black')
            hold(app.Intensity);
            for ts = 1:length(ts2plot)
                plot(app.Intensity, [ts2plot(ts)+0.5, ts2plot(ts)+0.5], [0, 600], 'k--') 
            end
            ylabel('Intensity (photon counts)')
            title('Intensity traces','FontSize', 12)
            set(gca, 'XTick', [], 'Visible', 'off')
            hold off
            
            subplot(2,1,2)
            hold on
            plot(app.Fluorescence, 1:length(tau2plot), tau2plot, 'LineWidth', 1.25, 'Color', 'blue')
            hold(app.Fluorescence);
            for ts = 1:length(ts2plot)
                plot(app.Fluorescence, [ts2plot(ts)+0.5, ts2plot(ts)+0.5], [0, 6], 'k--') 
            end
            hold off
            title('Lifetime traces','FontSize', 12)
            xlabel('Time (x 15 ms)')
            ylabel('Lifetime (ns)')
            % Change the figure size
            set(gcf, 'position', [100, 100, 1000, 400], 'Visible', 'off')
        end

        % Value changed function: SaveNamePanel
        function SaveNamePanelValueChanged(app, event)
            value = app.SaveNamePanel.Value;
            app.saveLifetimeName = value;
        end

        % Button pushed function: SelectFilesforebFRETButton
        function SelectFilesforebFRETButtonPushed(app, event)
           [file, path] = uigetfile(app.savepath);
           app.matFile = fullfile(path, file);
        end

        % Button pushed function: ebFRETTransformationButton
        function ebFRETTransformationButtonPushed(app, event)
            [folder, ~, ~] = fileparts(app.matFile);
            load(app.matFile)
            num_track = size(tau_track);
            readyData = [];
            timestep = [];
            b = 1;
            for i = 1:num_track
               a = 1 ;
               num_obs = length(tau_track{i});
               temp = [i*ones(num_obs,1),(tau_track{i})', 1*ones(num_obs,1)];
               readyData = [readyData;temp];    
               b = b + num_obs;
               timestep = [timestep, num_obs];
            end
            disp(['There is a total of ', num2str(i), ' trajectories ...'])
            % Save the file ...
            dlmwrite([folder, '\', app.ebFRETSaveName, '.dat'],readyData);
            disp(['Save the data to ''', [folder, '\', app.ebFRETSaveName, '.dat'], ''''])
        end

        % Value changed function: SelectFilesforebFRETPanel
        function SelectFilesforebFRETPanelValueChanged(app, event)
            value = app.SelectFilesforebFRETPanel.Value;
            app.ebFRETSaveName = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LifetimeThresholdPanel = uipanel(app.UIFigure);
            app.LifetimeThresholdPanel.Title = 'Lifetime Threshold';
            app.LifetimeTracesButton = uibutton(app.LifetimeThresholdPanel, 'push');
            app.LifetimeTracesButton.ButtonPushedFcn = createCallbackFcn(app, @LifetimeTracesButtonPushed, true);
            app.LifetimeTracesButton.Text = 'Lifetime Traces';
            app.MaxLifetimeEditField = uilabel(app.LifetimeThresholdPanel);
            app.MaxLifetimeEditField.Text = 'Max. Lifetime';
            app.MinLifetimeEditField = uilabel(app.LifetimeThresholdPanel);
            app.MinLifetimeEditField.Text = 'Min. Lifetime';
            app.SaveNameEditField = uilabel(app.LifetimeThresholdPanel);
            app.SaveNameEditField.Text = 'Save Name';
            app.MinLifetimePanel = uieditfield(app.LifetimeThresholdPanel, 'numeric');
            app.MinLifetimePanel.ValueChangedFcn = createCallbackFcn(app, @MinLifetimePanelValueChanged, true);
            app.MaxLifetimePanel = uieditfield(app.LifetimeThresholdPanel, 'numeric');
            app.MaxLifetimePanel.ValueChangedFcn = createCallbackFcn(app, @MaxLifetimePanelValueChanged, true);
            app.SaveNamePanel = uieditfield(app.LifetimeThresholdPanel, 'text');
            app.SaveNamePanel.ValueChangedFcn = createCallbackFcn(app, @SaveNamePanelValueChanged, true);
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'TD_MLE', 'Option 2', 'Option 3', 'Option 4'};
            app.DropDown.Value = 'TD_MLE';
            app.LifetimeAnalysisMethodLabel = uilabel(app.UIFigure);
            app.LifetimeAnalysisMethodLabel.Text = 'Lifetime Analysis Method';
            app.TrajectoryInformationPanel = uipanel(app.UIFigure);
            app.TrajectoryInformationPanel.Title = 'Trajectory Information';
            app.IntensityandDurationThresholdPanel = uipanel(app.UIFigure);
            app.IntensityandDurationThresholdPanel.Title = 'Intensity and Duration Threshold';
            app.MinDurationEditField = uilabel(app.IntensityandDurationThresholdPanel);
            app.MinDurationEditField.Text = 'Min. Duration';
            app.MaxCountEditField = uilabel(app.IntensityandDurationThresholdPanel);
            app.MaxCountEditField.Text = 'Max. Count';
            app.MaxDurationEditField = uilabel(app.IntensityandDurationThresholdPanel);
            app.MaxDurationEditField.Text = 'Max. Duration';
            app.ExtractTrajectoriesButton = uibutton(app.IntensityandDurationThresholdPanel, 'push');
            app.ExtractTrajectoriesButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractTrajectoriesButtonPushed, true);
            app.ExtractTrajectoriesButton.Text = 'Extract Trajectories';
            app.MaxCountPanel = uieditfield(app.IntensityandDurationThresholdPanel, 'numeric');
            app.MaxCountPanel.ValueChangedFcn = createCallbackFcn(app, @MaxCountPanelValueChanged, true);
            app.MinDurationPanel = uieditfield(app.IntensityandDurationThresholdPanel, 'numeric');
            app.MinDurationPanel.ValueChangedFcn = createCallbackFcn(app, @MinDurationPanelValueChanged, true);
            app.MaxDurationPanel = uieditfield(app.IntensityandDurationThresholdPanel, 'numeric');
            app.MaxDurationPanel.ValueChangedFcn = createCallbackFcn(app, @MaxDurationPanelValueChanged, true);
            app.FluoroOpenNameEditField = uieditfield(app.UIFigure, 'text');
            app.IRFOpenNameEditField = uieditfield(app.UIFigure, 'text');
            app.SavePathEditField = uieditfield(app.UIFigure, 'text');
            app.OpenPathEditField = uieditfield(app.UIFigure, 'text');
            app.FluorOpenNameButton = uibutton(app.UIFigure, 'push');
            app.FluorOpenNameButton.ButtonPushedFcn = createCallbackFcn(app, @FluorOpenNameButtonPushed, true);
            app.FluorOpenNameButton.Text = 'FluorOpenName';
            app.IRFOpenNameButton = uibutton(app.UIFigure, 'push');
            app.IRFOpenNameButton.ButtonPushedFcn = createCallbackFcn(app, @IRFOpenNameButtonPushed, true);
            app.IRFOpenNameButton.Text = 'IRFOpenName';
            app.SavePathButton = uibutton(app.UIFigure, 'push');
            app.SavePathButton.ButtonPushedFcn = createCallbackFcn(app, @SavePathButtonPushed, true);
            app.SavePathButton.Text = 'SavePath';
            app.OpenPathButton = uibutton(app.UIFigure, 'push');
            app.OpenPathButton.ButtonPushedFcn = createCallbackFcn(app, @OpenPathButtonPushed, true);
            app.OpenPathButton.Text = 'OpenPath';
            app.VisualizationPanel = uipanel(app.UIFigure);
            app.VisualizationPanel.Title = 'Visualization';
            app.Intensity = uiaxes(app.VisualizationPanel);
            title(app.Intensity, 'Intensity Traces')
            xlabel(app.Intensity, 'X')
            ylabel(app.Intensity, 'Y')
            zlabel(app.Intensity, 'Z')
            app.Fluorescence = uiaxes(app.VisualizationPanel);
            title(app.Fluorescence, 'Fluorescence Lifetime Traces')
            xlabel(app.Fluorescence, 'X')
            ylabel(app.Fluorescence, 'Y')
            zlabel(app.Fluorescence, 'Z')
            app.Lifetimes = uiaxes(app.VisualizationPanel);
            title(app.Lifetimes, 'Fluorescence Lifetime Traces + ebFRET Results')
            xlabel(app.Lifetimes, 'X')
            ylabel(app.Lifetimes, 'Y')
            zlabel(app.Lifetimes, 'Z')
            app.ebFRETButton = uibutton(app.UIFigure, 'push');
            app.ebFRETButton.Text = 'ebFRET';
            app.unboundstateLabel = uilabel(app.UIFigure);
            app.unboundstateLabel.Text = '1 - unbound state';
            app.bondstateLabel = uilabel(app.UIFigure);
            app.bondstateLabel.Text = '2 - bond state';
            app.KineticsRateCalculationButton = uibutton(app.UIFigure, 'push');
            app.KineticsRateCalculationButton.Text = 'Kinetics Rate Calculation';
            app.SaveresultsasButton = uibutton(app.UIFigure, 'push');
            app.SaveresultsasButton.Text = 'Save results as ...';
            app.Panel = uipanel(app.UIFigure);
            app.P11EditFieldLabel = uilabel(app.Panel);
            app.P11EditFieldLabel.Text = 'P11';
            app.P11EditField = uieditfield(app.Panel, 'text');
            app.P12EditFieldLabel = uilabel(app.Panel);
            app.P12EditFieldLabel.Text = 'P12';
            app.P12EditField = uieditfield(app.Panel, 'text');
            app.P21EditFieldLabel = uilabel(app.Panel);
            app.P21EditFieldLabel.Text = 'P21';
            app.P21EditField = uieditfield(app.Panel, 'text');
            app.P22EditFieldLabel = uilabel(app.Panel);
            app.P22EditFieldLabel.Text = 'P22';
            app.P22EditField = uieditfield(app.Panel, 'text');
            app.konEditFieldLabel = uilabel(app.Panel);
            app.konEditFieldLabel.Text = 'k''on';
            app.konEditField = uieditfield(app.Panel, 'text');
            app.koffEditFieldLabel = uilabel(app.Panel);
            app.koffEditFieldLabel.Text = 'k''off';
            app.koffEditField = uieditfield(app.Panel, 'text');
            app.kaEditFieldLabel = uilabel(app.Panel);
            app.kaEditFieldLabel.Text = 'ka';
            app.kaEditField = uieditfield(app.Panel, 'text');
            app.SelectFilesforebFRETButton = uibutton(app.UIFigure, 'push');
            app.SelectFilesforebFRETButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFilesforebFRETButtonPushed, true);
            app.SelectFilesforebFRETButton.Text = 'Select Files for ebFRET';
            app.SelectFilesforebFRETPanel = uieditfield(app.UIFigure, 'text');
            app.SelectFilesforebFRETPanel.ValueChangedFcn = createCallbackFcn(app, @SelectFilesforebFRETPanelValueChanged, true);
            app.ebFRETTransformationButton = uibutton(app.UIFigure, 'push');
            app.ebFRETTransformationButton.ButtonPushedFcn = createCallbackFcn(app, @ebFRETTransformationButtonPushed, true);
            app.ebFRETTransformationButton.Text = 'ebFRET Transformation';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui_3D_SMT

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end